classdef Shockwave_Calc < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                        matlab.ui.Figure
		GridLayout                      matlab.ui.container.GridLayout
		LeftPanel                       matlab.ui.container.Panel
		GridLayout2                     matlab.ui.container.GridLayout
		ExitPressureUnit                matlab.ui.control.Label
		CalculateButton                 matlab.ui.control.Button
		DivergingNozzleLengthEditField  matlab.ui.control.NumericEditField
		DivergingNozzleLengthEditFieldLabel  matlab.ui.control.Label
		LengthUnit                      matlab.ui.control.DropDown
		ConvergingNozzleLengthEditField  matlab.ui.control.NumericEditField
		ConvergingNozzleLengthEditFieldLabel  matlab.ui.control.Label
		OutletDiameterEditField         matlab.ui.control.NumericEditField
		OutletDiameterEditFieldLabel    matlab.ui.control.Label
		ThroatDiameterEditField         matlab.ui.control.NumericEditField
		ThroatDiameterEditFieldLabel    matlab.ui.control.Label
		DiameterUnit                    matlab.ui.control.DropDown
		InletDiameterEditField          matlab.ui.control.NumericEditField
		InletDiameterEditFieldLabel     matlab.ui.control.Label
		DiameterFunctionDropDown        matlab.ui.control.DropDown
		DiameterFunctionDropDownLabel   matlab.ui.control.Label
		CrossectionoftheNozzleLabel     matlab.ui.control.Label
		RUnit                           matlab.ui.control.DropDown
		REditField                      matlab.ui.control.NumericEditField
		REditFieldLabel                 matlab.ui.control.Label
		KEditField                      matlab.ui.control.NumericEditField
		KEditFieldLabel                 matlab.ui.control.Label
		FluidPropertiesLabel            matlab.ui.control.Label
		ExitPressureEditField           matlab.ui.control.NumericEditField
		ExitPressureEditFieldLabel      matlab.ui.control.Label
		InputMachNumberEditField        matlab.ui.control.NumericEditField
		InputMachNumberEditFieldLabel   matlab.ui.control.Label
		InletConditionsareDropDown      matlab.ui.control.DropDown
		InletConditionsareDropDownLabel  matlab.ui.control.Label
		InputTemperatureUnit            matlab.ui.control.DropDown
		InputTemperatureEditField       matlab.ui.control.NumericEditField
		InputTemperatureLabel           matlab.ui.control.Label
		InputPressureUnit               matlab.ui.control.DropDown
		InputPressureEditField          matlab.ui.control.NumericEditField
		InputPressureEditFieldLabel     matlab.ui.control.Label
		PressuresTemperaturesLabel      matlab.ui.control.Label
		RightPanel                      matlab.ui.container.Panel
		GridLayout3                     matlab.ui.container.GridLayout
		PositionShockwaveUnit           matlab.ui.control.Label
		PositionShockwaveEditField      matlab.ui.control.NumericEditField
		PositionShockwaveEditFieldLabel  matlab.ui.control.Label
		AreaShockwaveUnit               matlab.ui.control.Label
		AreaShockwaveEditField          matlab.ui.control.NumericEditField
		AreaShockwaveEditFieldLabel     matlab.ui.control.Label
		PercentageErrorEditField        matlab.ui.control.NumericEditField
		PercentageErrorEditFieldLabel   matlab.ui.control.Label
		PropertyPlottedDropDown         matlab.ui.control.DropDown
		PropertyPlottedDropDownLabel    matlab.ui.control.Label
		TemperatureExitUnit             matlab.ui.control.Label
		TemperatureExitEditField        matlab.ui.control.NumericEditField
		TemperatureatExitLabel          matlab.ui.control.Label
		PressureExitEditFieldUnit       matlab.ui.control.Label
		PressureExitEditField           matlab.ui.control.NumericEditField
		PressureUpstreamShockwaveEditFieldLabel_7  matlab.ui.control.Label
		MaExitEditField                 matlab.ui.control.NumericEditField
		PressureUpstreamShockwaveEditFieldLabel_6  matlab.ui.control.Label
		VelocityExitUnit                matlab.ui.control.Label
		VelocityExitEditField           matlab.ui.control.NumericEditField
		PressureUpstreamShockwaveEditFieldLabel_5  matlab.ui.control.Label
		MaDownstreamShockwaveEditField  matlab.ui.control.NumericEditField
		TemperatureUpstreamShockwaveEditFieldLabel_4  matlab.ui.control.Label
		MaUpstreamShockwaveEditField    matlab.ui.control.NumericEditField
		PressureUpstreamShockwaveEditFieldLabel_4  matlab.ui.control.Label
		VelocityDownstreamShockwaveUnit  matlab.ui.control.Label
		VelocityDownstreamShockwaveEditField  matlab.ui.control.NumericEditField
		TemperatureUpstreamShockwaveEditFieldLabel_3  matlab.ui.control.Label
		VelocityUpstreamShockwaveUnit   matlab.ui.control.Label
		VelocityUpstreamShockwaveEditField  matlab.ui.control.NumericEditField
		PressureUpstreamShockwaveEditFieldLabel_3  matlab.ui.control.Label
		TemperatureDownstreamShockwaveUnit  matlab.ui.control.Label
		TemperatureDownstreamShockwaveEditField  matlab.ui.control.NumericEditField
		TemperatureUpstreamShockwaveEditFieldLabel_2  matlab.ui.control.Label
		TemperatureUpstreamShockwaveUnit  matlab.ui.control.Label
		TemperatureUpstreamShockwaveEditField  matlab.ui.control.NumericEditField
		TemperatureUpstreamShockwaveEditFieldLabel  matlab.ui.control.Label
		PressureDownstreamShockwaveUnit  matlab.ui.control.Label
		PressureDownstreamShockwaveEditField  matlab.ui.control.NumericEditField
		PressureUpstreamShockwaveEditFieldLabel_2  matlab.ui.control.Label
		PressureUpstreamUnit            matlab.ui.control.Label
		PressureUpstreamShockwaveEditField  matlab.ui.control.NumericEditField
		PressureUpstreamShockwaveEditFieldLabel  matlab.ui.control.Label
		DuctPropertiesAxis              matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: CalculateButton
		function CalculateButtonPushed(app, event)
			global x D % Declare the variables as global
			% Get the values of the duct dimensions
			Di = app.InletDiameterEditField.Value; % Inner diameter of the duct
			Dt = app.ThroatDiameterEditField.Value; % Throat diameter of the duct
			De = app.OutletDiameterEditField.Value; % Exit diameter of the duct
			D_units = app.DiameterUnit.Value; % Get the units of the inner diameter
			% Get the values of the duct lengths
			Lc = app.ConvergingNozzleLengthEditField.Value; % Length of the converging section of the duct
			Ld = app.DivergingNozzleLengthEditField.Value; % Length of the diverging section of the duct
			L_u = app.LengthUnit.Value; % Get the units of the length
			func = app.DiameterFunctionDropDown.Value; % Function to be used for the calculation of the area
			% Get the values of the fluid properties
			K = app.KEditField.Value; % Specific heat ratio
			R = app.REditField.Value; % Gas constant
			R_u = app.RUnit.Value; % Get the units of the gas constant
			% Get the values of the flow properties
			Pi = app.InputPressureEditField.Value; % Input pressure
			Pi_u = app.InputPressureUnit.Value; % Get the units of the input pressure
			Ti = app.InputTemperatureEditField.Value; % Input temperature
			Ti_u = app.InputTemperatureUnit.Value; % Get the units of the input temperature
			InletConditions = app.InletConditionsareDropDown.Value; % Get the inlet conditions
			Ma_i = app.InputMachNumberEditField.Value; % Input Mach number
			Pe = app.ExitPressureEditField.Value; % Exit pressure
			
			% Output Figures
			Prop_Axis = app.DuctPropertiesAxis; % Axis for the properties plot
			
			% Output values
			Area_Shockwave = app.AreaShockwaveEditField;
			Area_Shockwave_u = app.AreaShockwaveUnit;
			X_Shockwave = app.PositionShockwaveEditField;
			X_Shockwave_u = app.PositionShockwaveUnit;
			Calc_Tx = app.TemperatureUpstreamShockwaveEditField; % Temperature upstream of the shockwave
			Calc_Tx_u = app.TemperatureUpstreamShockwaveUnit; % Units of the temperature upstream of the shockwave
			Calc_Ty = app.TemperatureDownstreamShockwaveEditField; % Temperature downstream of the shockwave
			Calc_Ty_u = app.TemperatureDownstreamShockwaveUnit; % Units of the temperature downstream of the shockwave
			Calc_Px = app.PressureUpstreamShockwaveEditField; % Pressure upstream of the shockwave
			Calc_Px_u = app.PressureUpstreamUnit; % Units of the pressure upstream of the shockwave
			Calc_Py = app.PressureDownstreamShockwaveEditField; % Pressure downstream of the shockwave
			Calc_Py_u = app.PressureDownstreamShockwaveUnit; % Units of the pressure downstream of the shockwave
			Calc_Vx = app.VelocityUpstreamShockwaveEditField; % Velocity upstream of the shockwave
			Calc_Vx_u = app.VelocityUpstreamShockwaveUnit; % Units of the velocity upstream of the shockwave
			Calc_Vy = app.VelocityDownstreamShockwaveEditField; % Velocity downstream of the shockwave
			Calc_Vy_u = app.VelocityDownstreamShockwaveUnit; % Units of the velocity downstream of the shockwave
			Calc_Ma_x = app.MaUpstreamShockwaveEditField; % Upstream Mach number
			Calc_Ma_y = app.MaDownstreamShockwaveEditField; % Downstream Mach number
			Calc_Ma = app.MaExitEditField; % Exit Mach number
			Calc_Te = app.TemperatureExitEditField; % Exit temperature
			Calc_Te_u = app.TemperatureExitUnit; % Units of the exit temperature
			Calc_Ve = app.VelocityExitEditField; % Exit velocity
			Calc_Ve_u = app.VelocityExitUnit; % Units of the exit velocity
			Calc_Pe = app.PressureExitEditField; % Exit pressure
			Calc_Pe_u = app.PressureExitEditFieldUnit; % Units of the exit pressure
			Calc_Error = app.PercentageErrorEditField;
			app.PropertyPlottedDropDown.Value = "Duct Geometry"; % Set the property plotted drop down to Ma as a default
			% Convert all values to SI units
			[Pi, Ti, Pe, R, Di, Dt, De, Lc, Ld] = Input_SI_Conversion(Pi,Ti,Pe, Pi_u,Ti_u,R,R_u,Di,Dt,De,D_units,Lc,Ld,L_u); % Convert all inputs to SI units

			% calculate the duct area
			[A,D,x,Index_Throat] = Duct_Area(Di,Dt,De,Lc,Ld, func); % Calculate the area of the duct based on the duct dimensions

			% Convert static conditions to total conditions if needed
			[P0x,T0]=Total_Condition_Convert(Pi,Ti,Ma_i,K,InletConditions); % Convert the static conditions to total conditions if needed

			% Check the flow regime
			[check] = SWcheck(A,Index_Throat,K,P0x,Pe); % Check the flow regime based on the input parameters
			if check ~= 1
				% If the flow regime would not result in a shockwave, display an error message
				errordlg('The flow regime would not result in a shockwave. Please check the input parameters.','Error');
				return
			end

			% Shockwave Detector function
			[iAsw,Ma_x,Ma_y,Px,Py,Tx,Ty,Vx,Vy,Ma_e,P0y,error]  = Shockwave_Detector(K,R,P0x,T0,Pe,A,Index_Throat); % Calculate the shockwave properties
		
			% Calculate the properties of the flow
			global Ma P T V  % Declare the variables as global

			[Ma, P, T, V] = Flow_Properties(K,R,P0x,T0,iAsw,Index_Throat,A,Ma_x,Px,Tx,Vx,Ma_y,P0y); % Calculate the properties of the flow
			
			% Convert the calculated values to the desired units
			[Px, Tx, Py, Ty, Vx, Vy, Asw, D, Dsw, P, T, V] = Output_SI_Conversion(Px,Py,P,Tx,Ty,T,Vx,Vy,V,A(iAsw),D,x(iAsw), Pi_u,Ti_u,D_units); % Convert the calculated values to the desired units
			
			Velocity_Unit = D_units + "/s"; % Units of the velocity
			% Update the output fields
			
			Area_Shockwave.Value = Asw;
			Area_Shockwave_u.Text = D_units + "^2";
			X_Shockwave.Value = Dsw;
			X_Shockwave_u.Text = D_units;

			Calc_Tx.Value = Tx; % Update the temperature upstream of the shockwave
			Calc_Tx_u.Text = Ti_u; % Update the units of the temperature upstream of the shockwave
			Calc_Ty.Value = Ty; % Update the temperature downstream of the shockwave
			Calc_Ty_u.Text = Ti_u; % Update the units of the temperature downstream of the shockwave
			Calc_Px.Value = Px; % Update the pressure upstream of the shockwave
			Calc_Px_u.Text = Pi_u; % Update the units of the pressure upstream of the shockwave
			Calc_Py.Value = Py; % Update the pressure downstream of the shockwave
			Calc_Py_u.Text = Pi_u; % Update the units of the pressure downstream of the shockwave
			Calc_Vx.Value = Vx; % Update the velocity upstream of the shockwave
			Calc_Vx_u.Text = Velocity_Unit; % Update the units of the velocity upstream of the shockwave
			Calc_Vy.Value = Vy; % Update the velocity downstream of the shockwave
			Calc_Vy_u.Text = Velocity_Unit; % Update the units of the velocity downstream of the shockwave
			Calc_Ma.Value = Ma_e; % Update the exit Mach number
			Calc_Ma_x.Value = Ma_x; % Update the upstream Mach number
			Calc_Ma_y.Value = Ma_y; % Update the downstream Mach number
			Calc_Te.Value = T(end); % Update the exit temperature
			Calc_Te_u.Text = Ti_u; % Update the units of the exit temperature
			Calc_Ve.Value = V(end); % Update the exit velocity
			Calc_Ve_u.Text = Velocity_Unit; % Update the units of the exit velocity
			Calc_Pe.Value = P(end); % Update the exit pressure
			Calc_Pe_u.Text = Pi_u; % Update the units of the exit pressure
			Calc_Error.Value = error * 100;
			
			Properties_Plot("Duct Geometry", D, D_units, x, L_u,Prop_Axis ); % Plot the properties of the flow
		end

		% Value changed function: PropertyPlottedDropDown
		function PropertyPlottedDropDownValueChanged(app, event)
			global Ma P T V x D  % Declare the variables as global
			
			L_u = app.LengthUnit.Value; % Get the units of the length
			Prop_Axis = app.DuctPropertiesAxis; % Axis for the properties plot

			Property = app.PropertyPlottedDropDown.Value; % Get the property to be plotted
			if Property == "Duct Geometry"
				Unit = app.DiameterUnit.Value;
				Properties_Plot("Duct Geometry", D, Unit, x, L_u,Prop_Axis );
			elseif Property == "Mach Number"
				Properties_Plot(Property, Ma, 0, x, L_u,Prop_Axis ); % Plot the Mach number
			elseif Property == "Pressure"
				Unit = app.PressureUpstreamUnit.Text; % Get the units of the pressure
				Properties_Plot(Property, P, Unit, x, L_u,Prop_Axis ); % Plot the pressure
			elseif Property == "Temperature"
				Unit = app.TemperatureUpstreamShockwaveUnit.Text; % Get the units of the temperature
				Properties_Plot(Property, T, Unit, x, L_u,Prop_Axis ); % Plot the temperature
			elseif Property == "Velocity"
				Unit = app.VelocityUpstreamShockwaveUnit.Text; % Get the units of the velocity
				Properties_Plot(Property, V, Unit, x, L_u,Prop_Axis ); % Plot the velocity
			end
		end

		% Value changed function: InputPressureUnit
		function InputPressureUnitValueChanged(app, event)
			app.ExitPressureUnit.Value =  app.InputPressureUnit.Value; % Set pressure unit as input pressure
			
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {808, 808};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {313, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.GridLayout2 = uigridlayout(app.LeftPanel);
			app.PressuresTemperaturesLabel = uilabel(app.GridLayout2);
			app.PressuresTemperaturesLabel.Text = 'Pressures & Temperatures';
			app.InputPressureEditFieldLabel = uilabel(app.GridLayout2);
			app.InputPressureEditFieldLabel.Text = 'Input Pressure';
			app.InputPressureEditField = uieditfield(app.GridLayout2, 'numeric');
			app.InputPressureEditField.Limits = [0 Inf];
			app.InputPressureUnit = uidropdown(app.GridLayout2);
			app.InputPressureUnit.Items = {'Pa', 'kPa', 'MPa', 'bar', 'Psi', 'Ksi'};
			app.InputPressureUnit.ValueChangedFcn = createCallbackFcn(app, @InputPressureUnitValueChanged, true);
			app.InputPressureUnit.Value = 'kPa';
			app.InputTemperatureLabel = uilabel(app.GridLayout2);
			app.InputTemperatureLabel.Text = ' Input Temperature';
			app.InputTemperatureEditField = uieditfield(app.GridLayout2, 'numeric');
			app.InputTemperatureEditField.Limits = [0 Inf];
			app.InputTemperatureUnit = uidropdown(app.GridLayout2);
			app.InputTemperatureUnit.Items = {'K', '°C', '°F'};
			app.InputTemperatureUnit.Value = 'K';
			app.InletConditionsareDropDownLabel = uilabel(app.GridLayout2);
			app.InletConditionsareDropDownLabel.Text = 'Inlet Conditions are:';
			app.InletConditionsareDropDown = uidropdown(app.GridLayout2);
			app.InletConditionsareDropDown.Items = {'Total', 'Static'};
			app.InletConditionsareDropDown.Value = 'Total';
			app.InputMachNumberEditFieldLabel = uilabel(app.GridLayout2);
			app.InputMachNumberEditFieldLabel.Text = 'Input Mach Number';
			app.InputMachNumberEditField = uieditfield(app.GridLayout2, 'numeric');
			app.InputMachNumberEditField.Limits = [0 Inf];
			app.ExitPressureEditFieldLabel = uilabel(app.GridLayout2);
			app.ExitPressureEditFieldLabel.Text = 'Exit Pressure';
			app.ExitPressureEditField = uieditfield(app.GridLayout2, 'numeric');
			app.ExitPressureEditField.Limits = [0 Inf];
			app.FluidPropertiesLabel = uilabel(app.GridLayout2);
			app.FluidPropertiesLabel.Text = 'Fluid Properties';
			app.KEditFieldLabel = uilabel(app.GridLayout2);
			app.KEditFieldLabel.Text = 'K';
			app.KEditField = uieditfield(app.GridLayout2, 'numeric');
			app.KEditField.Limits = [0 Inf];
			app.KEditField.Value = 1.4;
			app.REditFieldLabel = uilabel(app.GridLayout2);
			app.REditFieldLabel.Text = 'R';
			app.REditField = uieditfield(app.GridLayout2, 'numeric');
			app.REditField.Limits = [0 Inf];
			app.REditField.Value = 287;
			app.RUnit = uidropdown(app.GridLayout2);
			app.RUnit.Items = {'kJ/kgK', 'J/kgK'};
			app.RUnit.Value = 'J/kgK';
			app.CrossectionoftheNozzleLabel = uilabel(app.GridLayout2);
			app.CrossectionoftheNozzleLabel.Text = 'Crossection of the Nozzle';
			app.DiameterFunctionDropDownLabel = uilabel(app.GridLayout2);
			app.DiameterFunctionDropDownLabel.Text = 'Diameter Function';
			app.DiameterFunctionDropDown = uidropdown(app.GridLayout2);
			app.DiameterFunctionDropDown.Items = {'Linear', 'Quadratic'};
			app.DiameterFunctionDropDown.Value = 'Quadratic';
			app.InletDiameterEditFieldLabel = uilabel(app.GridLayout2);
			app.InletDiameterEditFieldLabel.Text = 'Inlet Diameter';
			app.InletDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DiameterUnit = uidropdown(app.GridLayout2);
			app.DiameterUnit.Items = {'mm', 'cm', 'm', 'ft', 'in'};
			app.DiameterUnit.Value = 'm';
			app.ThroatDiameterEditFieldLabel = uilabel(app.GridLayout2);
			app.ThroatDiameterEditFieldLabel.Text = 'Throat Diameter';
			app.ThroatDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
			app.OutletDiameterEditFieldLabel = uilabel(app.GridLayout2);
			app.OutletDiameterEditFieldLabel.Text = 'Outlet Diameter';
			app.OutletDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
			app.ConvergingNozzleLengthEditFieldLabel = uilabel(app.GridLayout2);
			app.ConvergingNozzleLengthEditFieldLabel.Text = 'Converging Nozzle Length';
			app.ConvergingNozzleLengthEditField = uieditfield(app.GridLayout2, 'numeric');
			app.LengthUnit = uidropdown(app.GridLayout2);
			app.LengthUnit.Items = {'mm', 'cm', 'm', 'ft', 'in'};
			app.LengthUnit.Value = 'm';
			app.DivergingNozzleLengthEditFieldLabel = uilabel(app.GridLayout2);
			app.DivergingNozzleLengthEditFieldLabel.Text = 'Diverging Nozzle Length';
			app.DivergingNozzleLengthEditField = uieditfield(app.GridLayout2, 'numeric');
			app.CalculateButton = uibutton(app.GridLayout2, 'push');
			app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
			app.CalculateButton.Text = 'Calculate';
			app.ExitPressureUnit = uilabel(app.GridLayout2);
			app.ExitPressureUnit.Text = 'kPa';
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout3 = uigridlayout(app.RightPanel);
			app.DuctPropertiesAxis = uiaxes(app.GridLayout3);
			title(app.DuctPropertiesAxis, 'Propeties against Duct')
			xlabel(app.DuctPropertiesAxis, 'X')
			ylabel(app.DuctPropertiesAxis, 'Y')
			zlabel(app.DuctPropertiesAxis, 'Z')
			app.PressureUpstreamShockwaveEditFieldLabel = uilabel(app.GridLayout3);
			app.PressureUpstreamShockwaveEditFieldLabel.Text = 'Pressure Upstream Shockwave';
			app.PressureUpstreamShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.PressureUpstreamShockwaveEditField.Editable = 'off';
			app.PressureUpstreamUnit = uilabel(app.GridLayout3);
			app.PressureUpstreamUnit.Text = 'Unit';
			app.PressureUpstreamShockwaveEditFieldLabel_2 = uilabel(app.GridLayout3);
			app.PressureUpstreamShockwaveEditFieldLabel_2.Text = 'Pressure Downstream Shockwave';
			app.PressureDownstreamShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.PressureDownstreamShockwaveEditField.Editable = 'off';
			app.PressureDownstreamShockwaveUnit = uilabel(app.GridLayout3);
			app.PressureDownstreamShockwaveUnit.Text = 'Unit';
			app.TemperatureUpstreamShockwaveEditFieldLabel = uilabel(app.GridLayout3);
			app.TemperatureUpstreamShockwaveEditFieldLabel.Text = 'Temperature Upstream Shockwave';
			app.TemperatureUpstreamShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.TemperatureUpstreamShockwaveEditField.Editable = 'off';
			app.TemperatureUpstreamShockwaveUnit = uilabel(app.GridLayout3);
			app.TemperatureUpstreamShockwaveUnit.Text = 'Unit';
			app.TemperatureUpstreamShockwaveEditFieldLabel_2 = uilabel(app.GridLayout3);
			app.TemperatureUpstreamShockwaveEditFieldLabel_2.Text = 'Temperature Downstream Shockwave';
			app.TemperatureDownstreamShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.TemperatureDownstreamShockwaveEditField.Editable = 'off';
			app.TemperatureDownstreamShockwaveUnit = uilabel(app.GridLayout3);
			app.TemperatureDownstreamShockwaveUnit.Text = 'Unit';
			app.PressureUpstreamShockwaveEditFieldLabel_3 = uilabel(app.GridLayout3);
			app.PressureUpstreamShockwaveEditFieldLabel_3.Text = 'Velocity Upstream Shockwave';
			app.VelocityUpstreamShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.VelocityUpstreamShockwaveEditField.Editable = 'off';
			app.VelocityUpstreamShockwaveUnit = uilabel(app.GridLayout3);
			app.VelocityUpstreamShockwaveUnit.Text = 'Unit';
			app.TemperatureUpstreamShockwaveEditFieldLabel_3 = uilabel(app.GridLayout3);
			app.TemperatureUpstreamShockwaveEditFieldLabel_3.Text = 'Velocity Downstream Shockwave';
			app.VelocityDownstreamShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.VelocityDownstreamShockwaveEditField.Editable = 'off';
			app.VelocityDownstreamShockwaveUnit = uilabel(app.GridLayout3);
			app.VelocityDownstreamShockwaveUnit.Text = 'Unit';
			app.PressureUpstreamShockwaveEditFieldLabel_4 = uilabel(app.GridLayout3);
			app.PressureUpstreamShockwaveEditFieldLabel_4.Text = 'Ma Upstream Shockwave';
			app.MaUpstreamShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.MaUpstreamShockwaveEditField.Editable = 'off';
			app.TemperatureUpstreamShockwaveEditFieldLabel_4 = uilabel(app.GridLayout3);
			app.TemperatureUpstreamShockwaveEditFieldLabel_4.Text = 'Ma Downstream Shockwave';
			app.MaDownstreamShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.MaDownstreamShockwaveEditField.Editable = 'off';
			app.PressureUpstreamShockwaveEditFieldLabel_5 = uilabel(app.GridLayout3);
			app.PressureUpstreamShockwaveEditFieldLabel_5.Text = 'Velocity at Exit';
			app.VelocityExitEditField = uieditfield(app.GridLayout3, 'numeric');
			app.VelocityExitEditField.Editable = 'off';
			app.VelocityExitUnit = uilabel(app.GridLayout3);
			app.VelocityExitUnit.Text = 'Unit';
			app.PressureUpstreamShockwaveEditFieldLabel_6 = uilabel(app.GridLayout3);
			app.PressureUpstreamShockwaveEditFieldLabel_6.Text = 'Mach Number at Exit';
			app.MaExitEditField = uieditfield(app.GridLayout3, 'numeric');
			app.MaExitEditField.Editable = 'off';
			app.PressureUpstreamShockwaveEditFieldLabel_7 = uilabel(app.GridLayout3);
			app.PressureUpstreamShockwaveEditFieldLabel_7.Text = 'Exit Pressure';
			app.PressureExitEditField = uieditfield(app.GridLayout3, 'numeric');
			app.PressureExitEditField.Editable = 'off';
			app.PressureExitEditFieldUnit = uilabel(app.GridLayout3);
			app.PressureExitEditFieldUnit.Text = 'Unit';
			app.TemperatureatExitLabel = uilabel(app.GridLayout3);
			app.TemperatureatExitLabel.Text = 'Temperature at Exit';
			app.TemperatureExitEditField = uieditfield(app.GridLayout3, 'numeric');
			app.TemperatureExitEditField.Editable = 'off';
			app.TemperatureExitUnit = uilabel(app.GridLayout3);
			app.TemperatureExitUnit.Text = 'Unit';
			app.PropertyPlottedDropDownLabel = uilabel(app.GridLayout3);
			app.PropertyPlottedDropDownLabel.Text = 'Property Plotted';
			app.PropertyPlottedDropDown = uidropdown(app.GridLayout3);
			app.PropertyPlottedDropDown.Items = {'Duct Geometry', 'Mach Number', 'Pressure', 'Temperature', 'Velocity'};
			app.PropertyPlottedDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyPlottedDropDownValueChanged, true);
			app.PropertyPlottedDropDown.Value = 'Duct Geometry';
			app.PercentageErrorEditFieldLabel = uilabel(app.GridLayout3);
			app.PercentageErrorEditFieldLabel.Text = 'Percentage Error';
			app.PercentageErrorEditField = uieditfield(app.GridLayout3, 'numeric');
			app.PercentageErrorEditField.Editable = 'off';
			app.AreaShockwaveEditFieldLabel = uilabel(app.GridLayout3);
			app.AreaShockwaveEditFieldLabel.Text = 'Area Shockwave';
			app.AreaShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.AreaShockwaveEditField.Editable = 'off';
			app.AreaShockwaveUnit = uilabel(app.GridLayout3);
			app.AreaShockwaveUnit.Text = 'Unit';
			app.PositionShockwaveEditFieldLabel = uilabel(app.GridLayout3);
			app.PositionShockwaveEditFieldLabel.Text = 'Position  Shockwave';
			app.PositionShockwaveEditField = uieditfield(app.GridLayout3, 'numeric');
			app.PositionShockwaveEditField.Editable = 'off';
			app.PositionShockwaveUnit = uilabel(app.GridLayout3);
			app.PositionShockwaveUnit.Text = 'Unit';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = Shockwave_Calc

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end